classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ImportAudioFileButton      matlab.ui.control.Button
        PlayButton                 matlab.ui.control.Button
        PauseButton                matlab.ui.control.Button
        InputOptionsDropDownLabel  matlab.ui.control.Label
        InputOptionsDropDown       matlab.ui.control.DropDown
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes2                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportAudioFileButton
        function ImportAudioFileButtonPushed(app, event)
            global y;
            global fs;
            global Player;
            [filename, filepath] = uigetfile({'*.wav'; '*.mp3'; '*.*'}, 'Pick an audio file');
            if ~ischar(filename); return; end
            fullname = fullfile(filepath, filename);
            [y, fs] = audioread(fullname);
            Player=audioplayer(y,fs);
            plot(app.UIAxes2,y)
            data_fft = fft(y);
            plot(app.UIAxes,abs(data_fft(:,1)));
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
           global Player;
            try
                play(Player)
            catch uhoh
                uiwait(msgbox('Could not open that file with audioread'));
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            global Player;
            stop(Player)
        end

        % Value changed function: InputOptionsDropDown
        function InputOptionsDropDownValueChanged(app, event)
            value = app.InputOptionsDropDown.Value;
            switch value
                case 'Import File'
                    app.ImportAudioFileButton.Visible = 'on';
                    %app.ImportAudioFileButton.Enable = 'on';
                case 'Sinusoidal'
                    app.ImportAudioFileButton.Visible = 'off';
                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportAudioFileButton = uibutton(app.UIFigure, 'push');
            app.ImportAudioFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAudioFileButtonPushed, true);
            app.ImportAudioFileButton.Visible = 'off';
            app.ImportAudioFileButton.Text = {'Import Audio File'; ''};
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Stop';
            app.InputOptionsDropDownLabel = uilabel(app.UIFigure);
            app.InputOptionsDropDownLabel.Text = 'Input Options';
            app.InputOptionsDropDown = uidropdown(app.UIFigure);
            app.InputOptionsDropDown.Items = {'Import File', 'Sinusoidal', 'Option 3', 'Option 4'};
            app.InputOptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @InputOptionsDropDownValueChanged, true);
            app.InputOptionsDropDown.Value = 'Import File';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'FFT of Input')
            xlabel(app.UIAxes, 'Frequency')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Graph of Input')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, 'Amplitude')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end